classdef AdeeptArmGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdeeptArmGUIUIFigure   matlab.ui.Figure
        SavedPosition2Panel    matlab.ui.container.Panel
        EditField_6            matlab.ui.control.NumericEditField
        EditField_5            matlab.ui.control.NumericEditField
        EditField_4            matlab.ui.control.NumericEditField
        SavedPosition3Panel    matlab.ui.container.Panel
        EditField_9            matlab.ui.control.NumericEditField
        EditField_8            matlab.ui.control.NumericEditField
        EditField_7            matlab.ui.control.NumericEditField
        SavedPosition1Panel    matlab.ui.container.Panel
        EditField_3            matlab.ui.control.NumericEditField
        EditField_2            matlab.ui.control.NumericEditField
        EditField              matlab.ui.control.NumericEditField
        NumericValuesPanel     matlab.ui.container.Panel
        J1ValueEditField       matlab.ui.control.NumericEditField
        J1ValueEditFieldLabel  matlab.ui.control.Label
        J2ValueEditField       matlab.ui.control.NumericEditField
        J2ValueEditFieldLabel  matlab.ui.control.Label
        J3ValueEditField       matlab.ui.control.NumericEditField
        J3ValueEditFieldLabel  matlab.ui.control.Label
        JointSlidersPanel      matlab.ui.container.Panel
        Joint1Slider           matlab.ui.control.Slider
        Joint1SliderLabel      matlab.ui.control.Label
        Joint2Slider           matlab.ui.control.Slider
        Joint2SliderLabel      matlab.ui.control.Label
        Joint3Slider           matlab.ui.control.Slider
        Joint3SliderLabel      matlab.ui.control.Label
        HomeButton             matlab.ui.control.Button
        GoButton               matlab.ui.control.Button
        axes_arm               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        jointAngles = [0, 0, 0]

        savedAngles1 = [0, 0, 0]
        savedAngles2 = [0, 0, 0]
        savedAngles3 = [0, 0, 0]
    end
    
    methods (Access = private)
        
        function updateArm(app)

            % Create the A homogeneous transformation matrices for the given jointAngles.
            A1 = create_A_matrix(64, 0, 0, app.jointAngles(1));
            A2 = create_A_matrix(0, 0, -90, app.jointAngles(2));
            A3 = create_A_matrix(0, 140, 0, app.jointAngles(3));

            % TODO: Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            T0_2 = A1 * A2;
            T0_3 = A1 * A2 * A3;

            % Use the current T matricies to transform the original patch vertices
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;

            % Update the patches with the newly transformed vertices
            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            % Prepare the axes_arm properties
            app.axes_arm.View = [90 -50];
            app.axes_arm.Visible = 'off';

            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            app.axes_arm.XLim = [-160 250];
            app.axes_arm.YLim = [-250 250];
            app.axes_arm.ZLim = [-22 22];

            % Create vertices for all the patches
            makeStlLink("AdeeptL0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("AdeeptL1.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));

            app.link2Patch = makeStlLink("AdeeptL2.stl", app.axes_arm, [198/255 172/255 20/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));

            app.link3Patch = makeStlLink("AdeeptL3.stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));

            app.updateArm()

        end

        % Callback function
        function Joint1SliderValueChanged(app, event)
            % Oops i fucked up
            
        end

        % Callback function
        function Joint1SliderValueChanging(app, event)
            % I fucked up again
            % changingValue = event.Value;
            % app.jointAngles(1) = changingValue;
            % 
            % app.J1ValueEditField.Value = app.jointAngles(1);
            % 
            % app.updateArm()
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.jointAngles(1) = app.J1ValueEditField.Value;
            app.jointAngles(2) = app.J2ValueEditField.Value;
            app.jointAngles(3) = app.J3ValueEditField.Value;

            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);

            app.updateArm()
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            homeValue = 0;
            app.jointAngles(1) = homeValue;
            app.jointAngles(2) = homeValue;
            app.jointAngles(3) = homeValue;

            app.J1ValueEditField.Value = app.jointAngles(1);
            app.J2ValueEditField.Value = app.jointAngles(2);
            app.J3ValueEditField.Value = app.jointAngles(3);

            app.updateArm()
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging2(app, event)
            changingValue = event.Value;
            app.jointAngles(1) = changingValue;

            app.J1ValueEditField.Value = app.jointAngles(1);

            app.updateArm()
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(2) = changingValue;

            app.J2ValueEditField.Value = app.jointAngles(2);

            app.updateArm()
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(3) = changingValue;

            app.J3ValueEditField.Value = app.jointAngles(3);

            app.updateArm()
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdeeptArmGUIUIFigure = uifigure('Visible', 'off');
            app.AdeeptArmGUIUIFigure.Name = 'Adeept Arm GUI';
            app.axes_arm = uiaxes(app.AdeeptArmGUIUIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.GoButton = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.HomeButton = uibutton(app.AdeeptArmGUIUIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.JointSlidersPanel = uipanel(app.AdeeptArmGUIUIFigure);
            app.JointSlidersPanel.Title = 'Joint Sliders';
            app.Joint3SliderLabel = uilabel(app.JointSlidersPanel);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.JointSlidersPanel);
            app.Joint3Slider.Limits = [-90 90];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.JointSlidersPanel);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.JointSlidersPanel);
            app.Joint2Slider.Limits = [-90 90];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint1SliderLabel = uilabel(app.JointSlidersPanel);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.JointSlidersPanel);
            app.Joint1Slider.Limits = [-90 90];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging2, true);
            app.NumericValuesPanel = uipanel(app.AdeeptArmGUIUIFigure);
            app.NumericValuesPanel.Title = 'Numeric Values';
            app.J3ValueEditFieldLabel = uilabel(app.NumericValuesPanel);
            app.J3ValueEditFieldLabel.Text = 'J3 Value';
            app.J3ValueEditField = uieditfield(app.NumericValuesPanel, 'numeric');
            app.J2ValueEditFieldLabel = uilabel(app.NumericValuesPanel);
            app.J2ValueEditFieldLabel.Text = 'J2 Value';
            app.J2ValueEditField = uieditfield(app.NumericValuesPanel, 'numeric');
            app.J1ValueEditFieldLabel = uilabel(app.NumericValuesPanel);
            app.J1ValueEditFieldLabel.Text = 'J1 Value';
            app.J1ValueEditField = uieditfield(app.NumericValuesPanel, 'numeric');
            app.SavedPosition1Panel = uipanel(app.AdeeptArmGUIUIFigure);
            app.SavedPosition1Panel.Title = 'Saved Position 1';
            app.EditField = uieditfield(app.SavedPosition1Panel, 'numeric');
            app.EditField_2 = uieditfield(app.SavedPosition1Panel, 'numeric');
            app.EditField_3 = uieditfield(app.SavedPosition1Panel, 'numeric');
            app.SavedPosition3Panel = uipanel(app.AdeeptArmGUIUIFigure);
            app.SavedPosition3Panel.Title = 'Saved Position 3';
            app.EditField_7 = uieditfield(app.SavedPosition3Panel, 'numeric');
            app.EditField_8 = uieditfield(app.SavedPosition3Panel, 'numeric');
            app.EditField_9 = uieditfield(app.SavedPosition3Panel, 'numeric');
            app.SavedPosition2Panel = uipanel(app.AdeeptArmGUIUIFigure);
            app.SavedPosition2Panel.Title = 'Saved Position 2';
            app.EditField_4 = uieditfield(app.SavedPosition2Panel, 'numeric');
            app.EditField_5 = uieditfield(app.SavedPosition2Panel, 'numeric');
            app.EditField_6 = uieditfield(app.SavedPosition2Panel, 'numeric');
            % Show the figure after all components are created
            app.AdeeptArmGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdeeptArmGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdeeptArmGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdeeptArmGUIUIFigure)
        end
    end
end