classdef Lab6_Car_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Lab6MQTTCarUIFigure   matlab.ui.Figure
        MiscellaneousPanel    matlab.ui.container.Panel
        BeepButton            matlab.ui.control.Button
        ClickintheareabelowtousethekeyboardLabel  matlab.ui.control.Label
        SpeedSlider           matlab.ui.control.Slider
        SpeedSliderLabel      matlab.ui.control.Label
        ModesPanel            matlab.ui.container.Panel
        SensorStreamingPanel  matlab.ui.container.Panel
        ServoHeadPanel        matlab.ui.container.Panel
    end

    
    properties (Access = private)
        mqClient
        priorKey = ""
    end
    
    methods (Access = private)
        
        function myCallback(app, topic, message)
            fprintf("Received: %s \n", message);
            messageStruct = jsondecode(message);
            messageType = messageStruct.type
            
            % Checking to see if there is a payload:
            if strcmp(messageType, "set_speeds")
                messagePayload = messageStruct.payload
            end

        end

        function sendMessage(app, messageType, messagePayload)
            messageStruct.type = messageType;
            if nargin > 2
                messageStruct.payload = messagePayload;
            end
            messageJson = jsonencode(messageStruct);

            % both write methods work
            % app.mqClient.write("chat", messageJson);
            write(app.mqClient, "me435/daffrojp/to_pi", messageJson);
            fprintf("Sent: %s \n", messageJson);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            % On Campus, subscribed to me
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");
            app.mqClient.subscribe("me435/daffrojp/#", "Callback", @app.myCallback);
        end

        % Key press function: Lab6MQTTCarUIFigure
        function Lab6MQTTCarUIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key, app.priorKey)
                return; % The key is being held down, ignore this one.
            end
            app.priorKey = key;
            
            speed = round(app.SpeedSlider.Value);

            if strcmp(key, "uparrow")
                app.sendMessage("set_speeds", [speed, speed, speed, speed])
            end

            if strcmp(key, "downarrow")
                app.sendMessage("set_speeds", [-speed, -speed, -speed, -speed])
            end

            if strcmp(key, "leftarrow")
                app.sendMessage("set_speeds", [-speed, speed, speed, -speed])
            end

            if strcmp(key, "rightarrow")
                app.sendMessage("set_speeds", [speed, -speed, -speed, speed])
            end


            if strcmp(key, "a")
                app.sendMessage("set_speeds", [-speed, -speed, speed, speed])
            end

            if strcmp(key, "d")
                app.sendMessage("set_speeds", [speed, speed, -speed, -speed])
            end
            
        end

        % Key release function: Lab6MQTTCarUIFigure
        function Lab6MQTTCarUIFigureKeyRelease(app, event)
            app.priorKey = "";
            app.sendMessage("stop")
        end

        % Button pushed function: BeepButton
        function BeepButtonPushed(app, event)
            app.sendMessage("beep")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Lab6MQTTCarUIFigure = uifigure('Visible', 'off');
            app.Lab6MQTTCarUIFigure.Name = 'Lab 6 MQTT Car';
            app.Lab6MQTTCarUIFigure.KeyPressFcn = createCallbackFcn(app, @Lab6MQTTCarUIFigureKeyPress, true);
            app.Lab6MQTTCarUIFigure.KeyReleaseFcn = createCallbackFcn(app, @Lab6MQTTCarUIFigureKeyRelease, true);
            app.ServoHeadPanel = uipanel(app.Lab6MQTTCarUIFigure);
            app.ServoHeadPanel.Title = 'Servo Head';
            app.SensorStreamingPanel = uipanel(app.Lab6MQTTCarUIFigure);
            app.SensorStreamingPanel.Title = 'Sensor Streaming';
            app.ModesPanel = uipanel(app.Lab6MQTTCarUIFigure);
            app.ModesPanel.Title = 'Modes';
            app.SpeedSliderLabel = uilabel(app.Lab6MQTTCarUIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.Lab6MQTTCarUIFigure);
            app.SpeedSlider.Value = 40;
            app.ClickintheareabelowtousethekeyboardLabel = uilabel(app.Lab6MQTTCarUIFigure);
            app.ClickintheareabelowtousethekeyboardLabel.Text = {'Click in the area below'; 'to use the keyboard.'};
            app.MiscellaneousPanel = uipanel(app.Lab6MQTTCarUIFigure);
            app.MiscellaneousPanel.Title = 'Miscellaneous';
            app.BeepButton = uibutton(app.MiscellaneousPanel, 'push');
            app.BeepButton.ButtonPushedFcn = createCallbackFcn(app, @BeepButtonPushed, true);
            app.BeepButton.Text = 'Beep';
            % Show the figure after all components are created
            app.Lab6MQTTCarUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab6_Car_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Lab6MQTTCarUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Lab6MQTTCarUIFigure)
        end
    end
end